classdef Chirag_Patel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Menue                   matlab.ui.container.Menu
        AnalyzerMenu            matlab.ui.container.Menu
        Toolbar                 matlab.ui.container.Toolbar
        PushTool2               matlab.ui.container.toolbar.PushTool
        SpanButton              matlab.ui.control.Button
        CenterFreqButton        matlab.ui.control.Button
        PowerButton             matlab.ui.control.Button
        BWButton                matlab.ui.control.Button
        FrequencyButton         matlab.ui.control.Button
        Lamp_3                  matlab.ui.control.Lamp
        Lamp_2                  matlab.ui.control.Lamp
        AnalyzerButton          matlab.ui.control.StateButton
        GeneratorButton         matlab.ui.control.StateButton
        Lamp                    matlab.ui.control.Lamp
        TurnofftheMarkerButton  matlab.ui.control.Button
        SetamarkeronPeakButton  matlab.ui.control.Button
        GenRFopONOFFButton      matlab.ui.control.StateButton
        SpanEditField           matlab.ui.control.NumericEditField
        CenterFreqEditField     matlab.ui.control.NumericEditField
        BWEditField             matlab.ui.control.NumericEditField
        PowerEditField          matlab.ui.control.NumericEditField
        FreqEditField           matlab.ui.control.NumericEditField
        ArcusMotorButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        obj1;
        obj;
        interface = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: GenRFopONOFFButton
        function GenRFopONOFFButtonValueChanged(app, event)
            value = app.GenRFopONOFFButton.Value;
            if (value==1)
                app.obj1.RF_OutPut_ON();
                app.Lamp.Color='green';
            else
                app.obj1.RF_OutPut_OFF();
                app.Lamp.Color='red';
            end
            
        end

        % Value changed function: GeneratorButton
        function GeneratorButtonValueChanged(app, event)
            value = app.GeneratorButton.Value;
            if (value==1)
                app.obj1 = Generator_MXG_N5182A();
                app.Lamp_2.Color='green';
            else
                app.obj1.Disconnect_Generator();
                app.Lamp_2.Color='red';
            end
                        
        end

        % Value changed function: AnalyzerButton
        function AnalyzerButtonValueChanged(app, event)
            value = app.AnalyzerButton.Value;
            if (value==1)
                app.obj = Analyzer_EXA_N9010A();
                app.Lamp_3.Color='green';
            else
                app.obj.close();
                app.Lamp_3.Color='red';
            end
            
        end

        % Button pushed function: SetamarkeronPeakButton
        function SetamarkeronPeakButtonPushed(app, event)
            app.obj.smp(1)
        end

        % Button pushed function: TurnofftheMarkerButton
        function TurnofftheMarkerButtonPushed(app, event)
            app.obj.moff(1) 
        end

        % Button pushed function: FrequencyButton
        function FrequencyButtonPushed(app, event)
            Frequency = app.FreqEditField.Value;
            app.obj1.SetFrequeny(num2str(Frequency))
        end

        % Button pushed function: PowerButton
        function PowerButtonPushed(app, event)
            Power = app.PowerEditField.Value;
            app.obj1.SetPowerLevel(num2str(Power))
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menue = uimenu(app.UIFigure);
            app.Menue.Text = 'Menue';
            app.AnalyzerMenu = uimenu(app.Menue);
            app.AnalyzerMenu.Text = 'Analyzer';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool2 = uipushtool(app.Toolbar);
            app.ArcusMotorButton = uibutton(app.UIFigure, 'push');
            app.ArcusMotorButton.Tag = 'Push Button';
            app.ArcusMotorButton.Text = 'Arcus Motor';
            app.FreqEditField = uieditfield(app.UIFigure, 'numeric');
            app.FreqEditField.Tag = 'Numeric';
            app.PowerEditField = uieditfield(app.UIFigure, 'numeric');
            app.PowerEditField.Tag = 'Numeric';
            app.BWEditField = uieditfield(app.UIFigure, 'numeric');
            app.BWEditField.Tag = 'Numeric';
            app.CenterFreqEditField = uieditfield(app.UIFigure, 'numeric');
            app.CenterFreqEditField.Tag = 'Numeric';
            app.SpanEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpanEditField.Tag = 'Numeric';
            app.GenRFopONOFFButton = uibutton(app.UIFigure, 'state');
            app.GenRFopONOFFButton.ValueChangedFcn = createCallbackFcn(app, @GenRFopONOFFButtonValueChanged, true);
            app.GenRFopONOFFButton.Tag = 'state button on and off';
            app.GenRFopONOFFButton.Text = 'Gen. RF o/p ON/OFF';
            app.SetamarkeronPeakButton = uibutton(app.UIFigure, 'push');
            app.SetamarkeronPeakButton.ButtonPushedFcn = createCallbackFcn(app, @SetamarkeronPeakButtonPushed, true);
            app.SetamarkeronPeakButton.Text = 'Set a marker on Peak';
            app.TurnofftheMarkerButton = uibutton(app.UIFigure, 'push');
            app.TurnofftheMarkerButton.ButtonPushedFcn = createCallbackFcn(app, @TurnofftheMarkerButtonPushed, true);
            app.TurnofftheMarkerButton.Text = 'Turn off the Marker';
            app.Lamp = uilamp(app.UIFigure);
            app.GeneratorButton = uibutton(app.UIFigure, 'state');
            app.GeneratorButton.ValueChangedFcn = createCallbackFcn(app, @GeneratorButtonValueChanged, true);
            app.GeneratorButton.Text = 'Generator ';
            app.AnalyzerButton = uibutton(app.UIFigure, 'state');
            app.AnalyzerButton.ValueChangedFcn = createCallbackFcn(app, @AnalyzerButtonValueChanged, true);
            app.AnalyzerButton.Text = 'Analyzer ';
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_3 = uilamp(app.UIFigure);
            app.FrequencyButton = uibutton(app.UIFigure, 'push');
            app.FrequencyButton.ButtonPushedFcn = createCallbackFcn(app, @FrequencyButtonPushed, true);
            app.FrequencyButton.Text = 'Frequency';
            app.BWButton = uibutton(app.UIFigure, 'push');
            app.BWButton.Text = 'BW';
            app.PowerButton = uibutton(app.UIFigure, 'push');
            app.PowerButton.ButtonPushedFcn = createCallbackFcn(app, @PowerButtonPushed, true);
            app.PowerButton.Text = 'Power';
            app.CenterFreqButton = uibutton(app.UIFigure, 'push');
            app.CenterFreqButton.Text = 'Center Freq.';
            app.SpanButton = uibutton(app.UIFigure, 'push');
            app.SpanButton.Text = 'Span';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Chirag_Patel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end